classdef MuscleAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Muscle_image              matlab.ui.control.UIAxes
        SelectOtherResultsButton  matlab.ui.control.Button
        MuscleThickness_image     matlab.ui.control.UIAxes
        MusclexpixelSliderLabel   matlab.ui.control.Label
        MusclexpixelSlider        matlab.ui.control.Slider
        FrameSliderLabel          matlab.ui.control.Label
        FrameSlider               matlab.ui.control.Slider
        NewVideoButton            matlab.ui.control.Button
        PlayButton                matlab.ui.control.StateButton
        SummaryTextAreaLabel      matlab.ui.control.Label
        SummaryTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        eco_matrix = [];
        eco_img_real_size = [];
        memoria_fascia_sup_inf = [];
        Results = [];
        Muscle_x_pixel = [];
        Muscle_frame = 1;
    end
    
    methods (Access = private)
        
        function plot_eco_MT(app)
            %Plotear eco con la linea donde se realiza la medida y MT vs frame
            %eco
            imshow(app.eco_matrix(:,:,app.Muscle_frame),app.eco_img_real_size, 'Parent', app.Muscle_image)
            hold(app.Muscle_image, 'on')
                plot(app.Muscle_image,[app.MusclexpixelSlider.Value app.MusclexpixelSlider.Value] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,1)],'yo','LineWidth',3)
                plot(app.Muscle_image,[app.MusclexpixelSlider.Value app.MusclexpixelSlider.Value] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,2) app.memoria_fascia_sup_inf(app.Muscle_frame,app.MusclexpixelSlider.Value,1)],'y-','LineWidth',3)
                plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,1),'r--','LineWidth',3)
                plot(app.Muscle_image,(1:size(app.eco_matrix(:,:,app.Muscle_frame),2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(app.Muscle_frame,:,2),'r--','LineWidth',3)
            hold(app.Muscle_image, 'off')
            
            %MT vs frame
            plot(app.MuscleThickness_image,(1:size(app.memoria_fascia_sup_inf,1)),(app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1)),'LineWidth',2) 
            hold(app.MuscleThickness_image, 'on')
                xline(app.MuscleThickness_image,app.Muscle_frame,'-.','LineWidth',2);
%                 xline(locs(1),'--','LineWidth',2);
%                 xline(locs(2),'--','LineWidth',2);
            hold(app.MuscleThickness_image, 'off')
        end
        
        
        function SummaryTextDisplay(app)
            app.SummaryTextArea.Value = {  ['Video Name: ', app.Results.Name] ...
                             , ['Duration[s]: ', num2str(app.Results.Duration)] ...
                             , ['Frame Rate: ', num2str(app.Results.Frame_rate)] ...
                             , ['Scale Factor[mm/pixel]: ', num2str(app.Results.Scale_factor)] ...
                             , ['Muscle x pixel: ', num2str(app.Results.Muscle_x_pixel)]};
        end


        function PlotEcoPlay(app)
            %Animación del funcionamiento del sistema
            Pause_t = 1/app.Results.Frame_rate;
            Value_MT_plot = (app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,2) - app.memoria_fascia_sup_inf(:,app.Muscle_x_pixel,1));
            for frame = 1:size(app.eco_matrix,3)

                imshow(app.eco_matrix(:,:,frame),app.eco_img_real_size, 'Parent', app.Muscle_image)
                hold(app.Muscle_image, 'on')
                    plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,1)],'yo','LineWidth',3)
                    plot(app.Muscle_image,[app.Muscle_x_pixel app.Muscle_x_pixel] * app.Results.Scale_factor,[app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,2) app.memoria_fascia_sup_inf(frame,app.Muscle_x_pixel,1)],'y-','LineWidth',3)
                    plot(app.Muscle_image,(1:size(app.eco_matrix,2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(frame,:,1),'r--','LineWidth',3)
                    plot(app.Muscle_image,(1:size(app.eco_matrix,2)) .* app.Results.Scale_factor, app.memoria_fascia_sup_inf(frame,:,2),'r--','LineWidth',3)
                hold(app.Muscle_image, 'off')
                
                plot(app.MuscleThickness_image,(1:size(app.memoria_fascia_sup_inf,1)),Value_MT_plot,'LineWidth',2) 
                hold(app.MuscleThickness_image, 'on')
                    xline(app.MuscleThickness_image,frame,'y-.','LineWidth',2);
%                 xline(locs(1),'--','LineWidth',2);
%                 xline(locs(2),'--','LineWidth',2);
                hold(app.MuscleThickness_image, 'off')
                
                pause(Pause_t)
            end 
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SelectOtherResultsButton
        function SelectOtherResultsButtonPushed(app, event)
            path_v = uigetdir();
            drawnow;
            figure(app.UIFigure)
            if path_v == 0
                return
            end
            app.eco_matrix = load(strcat(path_v,'\eco_memory.mat'));
            app.eco_matrix = app.eco_matrix.eco_memory;
            app.memoria_fascia_sup_inf = load(strcat(path_v,'\memoria_fascia_sup_inf.mat'));
            app.memoria_fascia_sup_inf = app.memoria_fascia_sup_inf.memoria_fascia_sup_inf;
            app.Results = load(strcat(path_v,'\Results.mat'));
            app.Results = app.Results.Results;
            app.eco_img_real_size = imref2d(size(app.eco_matrix(:,:,1)),app.Results.Scale_factor,app.Results.Scale_factor);
            app.Muscle_x_pixel = app.Results.Muscle_x_pixel;
            app.MusclexpixelSlider.Limits = [1 size(app.memoria_fascia_sup_inf,2)];
            app.MusclexpixelSlider.Value = app.Muscle_x_pixel;
            app.FrameSlider.Limits = [1 size(app.memoria_fascia_sup_inf,1)];
            app.FrameSlider.Value = app.Muscle_frame;
            app.Muscle_image.YLim = [0 size(app.eco_matrix,1) * app.Results.Scale_factor];
            app.Muscle_image.XLim = [0 size(app.eco_matrix,2) * app.Results.Scale_factor];
            app.plot_eco_MT
            app.SummaryTextDisplay
            
        end

        % Value changed function: MusclexpixelSlider
        function MusclexpixelSliderValueChanged(app, event)
            value = app.MusclexpixelSlider.Value;
            app.Muscle_x_pixel = round(value);
            app.MusclexpixelSlider.Value = app.Muscle_x_pixel; 
            app.plot_eco_MT
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged2(app, event)
            value = app.FrameSlider.Value;
            app.Muscle_frame = round(value);
            app.FrameSlider.Value = app.Muscle_frame;
            app.plot_eco_MT
        end

        % Button pushed function: NewVideoButton
        function NewVideoButtonPushed(app, event)
            addpath(strcat(pwd,'\Functions'))
            [app.eco_matrix, app.memoria_fascia_sup_inf, app.Results] = Measure();
            drawnow;
            figure(app.UIFigure)
            app.Muscle_frame = 1;        
            app.eco_img_real_size = imref2d(size(app.eco_matrix(:,:,1)),app.Results.Scale_factor,app.Results.Scale_factor);
            app.Muscle_x_pixel = app.Results.Muscle_x_pixel;
            app.MusclexpixelSlider.Limits = [1 size(app.memoria_fascia_sup_inf,2)];
            app.MusclexpixelSlider.Value = app.Muscle_x_pixel;
            app.FrameSlider.Limits = [1 size(app.memoria_fascia_sup_inf,1)];
            app.FrameSlider.Value = app.Muscle_frame;
            app.Muscle_image.YLim = [0 size(app.eco_matrix,1) * app.Results.Scale_factor];
            app.Muscle_image.XLim = [0 size(app.eco_matrix,2) * app.Results.Scale_factor];
            app.SummaryTextDisplay
            app.PlotEcoPlay
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            %value = app.PlayButton.Value;
            app.PlayButton.Enable = false;
            app.PlotEcoPlay
            app.PlayButton.Value = ~app.PlayButton.Value;
            app.PlayButton.Enable = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Muscle_image = uiaxes(app.UIFigure);
            title(app.Muscle_image, 'Muscle')
            xlabel(app.Muscle_image, '[mm]')
            ylabel(app.Muscle_image, '[mm]')
            app.SelectOtherResultsButton = uibutton(app.UIFigure, 'push');
            app.SelectOtherResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOtherResultsButtonPushed, true);
            app.SelectOtherResultsButton.Text = 'Select Other Results';
            app.MuscleThickness_image = uiaxes(app.UIFigure);
            title(app.MuscleThickness_image, 'Muscle Thickness')
            xlabel(app.MuscleThickness_image, 'Frame')
            ylabel(app.MuscleThickness_image, '[mm]')
            app.MusclexpixelSliderLabel = uilabel(app.UIFigure);
            app.MusclexpixelSliderLabel.Text = 'Muscle x pixel';
            app.MusclexpixelSlider = uislider(app.UIFigure);
            app.MusclexpixelSlider.ValueChangedFcn = createCallbackFcn(app, @MusclexpixelSliderValueChanged, true);
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged2, true);
            app.NewVideoButton = uibutton(app.UIFigure, 'push');
            app.NewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @NewVideoButtonPushed, true);
            app.NewVideoButton.Text = 'New Video';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.SummaryTextAreaLabel = uilabel(app.UIFigure);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.UIFigure);
            app.SummaryTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MuscleAnalysisApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end